classdef retinaDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RetinadetectionUIFigure    matlab.ui.Figure
        LoadrawimageButton         matlab.ui.control.Button
        LoadpatternButton          matlab.ui.control.Button
        CompareimagesButton        matlab.ui.control.Button
        SavedetectedpatternButton  matlab.ui.control.Button
        Label_compare              matlab.ui.control.Label
        Label_save                 matlab.ui.control.Label
        ShowcomparisoninnewwindowButton  matlab.ui.control.Button
        Label_compare_new_figure   matlab.ui.control.Label
    end

    
    properties (Access = private) 
        main_figure % handler to main figure
        zoom_figure % figure in which zoomed comparison will be shown
        ah1; ah2; ah3; ah4; ah5; % axes handlers in main figure
        ax; % axes in zoom
        pattern % loaded pattern (class object)
        raw_image % loaded raw image (class object)
        comparison % comparison result (structure)
    end
    
    methods (Access = private)
        
        function create_figure(app) %function to create figure and all of its axeses
            % deleting previous axes while creating new one
            if ~isempty(app.main_figure)
                delete(app.main_figure)
            end
            
            % setting positions
            app.main_figure = uifigure('Name','Results');
            app.main_figure.Position = [550, 150, 650, 500];
            app.ah1 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.1 0.6 0.23 0.3], 'Color', [0.4 0.4 0.4]);
            app.ah2 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.1 0.2 0.23 0.3], 'Color', [0.5 0.5 0.5]);
            app.ah3 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.6 0.23 0.3], 'Color', [0.5 0.5 0.5]);
            app.ah4 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.2 0.4 0.3], 'Color', [0.5 0.5 0.5]);
            app.ah5 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.6 0.1 0.4 0.3], 'Color', [0.5 0.5 0.5]);
            
            % raw image section
            text(app.ah1, 0.13, 0.5,'Load the raw image'); 
            set(app.ah1,'XTick',[])
            set(app.ah1,'YTick',[])
            set(app.ah1, 'box', 'on')
            
            set(app.ah3, 'visible', 'off')
            
            % pattern section
            text(app.ah2, 0.16, 0.5,'Load the pattern');
            set(app.ah2,'XTick',[])
            set(app.ah2,'YTick',[])
            set(app.ah2, 'box', 'on')
            
            % comparison section
            text(app.ah4, 0.3, 0.5, 'Compare images');
            set(app.ah4,'XTick',[])
            set(app.ah4,'YTick',[])
            set(app.ah4, 'box', 'on')
            
            axis(app.ah5, 'off')
        end
        
        function show_raw(app)
            try
            % delete previous comparison
            app.clear_comparison; 
            
            imagesc(app.ah1, app.raw_image.resized_image); 
            set(app.ah1, 'Units', 'normalized', 'Position', [0.1 0.6 0.23 0.3]);
            title(app.ah1, 'raw image')
            axis(app.ah1,'off')
            
            imagesc(app.ah3, app.raw_image.processed_image);
            colormap(app.ah3, 'gray');
            set(app.ah3, 'Units', 'normalized', 'Position', [0.5 0.6 0.23 0.3]);
            title(app.ah3, 'detected pattern')
            axis(app.ah3,'off')
            
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error') 
            end

        end
        
        function show_pattern(app)
            try
            % delete previous comparison
            app.clear_comparison;
            
            imagesc(app.ah2, app.pattern.processed_image);
            colormap(app.ah2, 'gray');
            set(app.ah2, 'Units', 'normalized', 'Position', [0.1 0.2 0.23 0.3]);
            title(app.ah2, 'loaded pattern')
            axis(app.ah2,'off')
            
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error') 
            end
        end
        
        function clear_comparison(app)
            try
            delete(app.ah4)
            app.ah4 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.5 0.2 0.4 0.3]);
            delete(app.zoom_figure) % deleting figure with zoom
            delete(app.ax) % deleting axes from zoom
            delete(app.ah5) %deleting axes with comparison
            
            text(app.ah4, 0.3, 0.5, 'Compare images');
            set(app.ah4,'XTick',[])
            set(app.ah4,'YTick',[])
            set(app.ah4, 'box', 'on')
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error') 
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.create_figure;
        end

        % Button pushed function: LoadrawimageButton
        function LoadrawimageButtonPushed(app, event)
            % function creating new window, if the previous was closed
            if ~isprop(app.main_figure, 'Position')
                app.create_figure;
                %showing closed image
                if ~isempty(app.pattern)
                    app.show_pattern;
                end
            end
            
            try
                app.raw_image = raw_eye_image;
                app.show_raw;
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error')
            end
            
        end

        % Button pushed function: SavedetectedpatternButton
        function SavedetectedpatternButtonPushed(app, event)
            if isempty(app.raw_image)
                app.Label_save.Text = 'first you must load raw image';
            else 
                try
                    app.Label_save.Text = '';
                    app.raw_image.savePattern;
                catch error
                    %app.warning_figure = uifigure;
                    uialert(app.main_figure, error.message, 'error')
                end
            end
        end

        % Button pushed function: LoadpatternButton
        function LoadpatternButtonPushed(app, event)
            % function creating new window, if the previous was closed
            if ~isprop(app.main_figure, 'Position')
                app.create_figure;
                % showing closed image
                if ~isempty(app.raw_image)
                    app.show_raw;
                end
            end
            
            try
                app.pattern = eye_pattern;
                app.pattern.get_final_eye_pattern;
                app.show_pattern;            
            catch error
                %app.warning_figure = uifigure;
                uialert(app.main_figure, error.message, 'error')
            end
        end

        % Button pushed function: CompareimagesButton
        function CompareimagesButtonPushed(app, event)
            % function to compare two loaded images
            try
                % function creating new window, if the previous was closed
                if ~isprop(app.main_figure, 'Position')
                    app.create_figure;
                    
                    % showing closed image
                    if ~isempty(app.pattern)
                    app.show_pattern;
                    end
                    
                    if ~isempty(app.raw_image)
                    app.show_raw;
                    end
                end
                
                % if any image is not loaded yet, show message 
                if isempty(app.pattern) || isempty(app.raw_image)
                    app.Label_compare.Text = 'first you must load two images';
                else
                    % hidding messages written on the label (they appear if
                    % there are not two images loaded)
                    app.Label_compare_new_figure.Text = '';
                    app.Label_compare.Text = '';
                    
                    % use of compare class
                    app.comparison = compare(app.raw_image.processed_image, app.pattern.processed_image);
                    msg_ssim = ['SSIM value is ',num2str(app.comparison.ssimval)]; % 1 means identical
                    msg_perc = [ num2str(app.comparison.percentage), '% of features are the same']; 
                    set(app.ah4, 'Units', 'normalized', 'Position', [0.5 0.2 0.4 0.3]);
                    imshowpair(app.comparison.best_pattern, app.raw_image.processed_image, 'montage', 'Parent', app.ah4);
                    title(app.ah4, {msg_ssim, msg_perc}) 
                    
                    % checking if there is only few features matched
                    if app.comparison.percentage < 3
                        delete(app.ah5)
                        app.ah5 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.6 0.1 0.4 0.3]);
                        set(app.ah5, 'visible', 'off');
                        text(app.ah5, 0.1, 0.3, 'your image may be low quality ' )
                        text(app.ah5, 0.1, 0.2, 'or present object that is not an eye')
                    % checking if there are some features matched and ssim
                    % value is relatively big
                    elseif (app.comparison.percentage > 20 && app.comparison.ssimval > 0.5)
                        delete(app.ah5)
                        app.ah5 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.6 0.1 0.4 0.3]);
                        set(app.ah5, 'visible', 'off');
                        text(app.ah5, 0.1, 0.3, 'eye from raw image belongs ' )
                        text(app.ah5, 0.1, 0.2, 'to the person from pattern')
                    else
                        delete(app.ah5)
                        app.ah5 = axes('Parent',app.main_figure,'Units','normalized','Position',[0.6 0.1 0.4 0.3]);
                        set(app.ah5, 'visible', 'off');
                        text(app.ah5, 0.1, 0.3, 'eye from raw image does not ' )
                        text(app.ah5, 0.1, 0.2, 'belong to the person from pattern')
                    end
                end
            catch error
                uialert(app.RetinadetectionUIFigure, error.message, 'error')
            end

        end

        % Button pushed function: ShowcomparisoninnewwindowButton
        function ShowcomparisoninnewwindowButtonPushed(app, event)
            % function to show comparison in new figure
            if isempty(app.comparison)
                app.Label_compare_new_figure.Text = 'first you must make a comparison';
            else 
                try
                    app.zoom_figure = uifigure('Name', 'Zoomed comparison');
                    app.ax = axes('Parent',app.zoom_figure);
                    showMatchedFeatures(app.comparison.best_pattern, app.raw_image.processed_image, app.comparison.matched_features.matchedP, app.comparison.matched_features.matchedD,'montage', 'Parent',app.ax);
                    msg = ['Detected ', num2str(app.comparison.score), ' matched features'];
                    title(app.ax, msg)
                catch error
                    uialert(app.main_figure, error.message, 'error')
                end
            end
        end

        % Close request function: RetinadetectionUIFigure
        function RetinadetectionUIFigureCloseRequest(app, event)
            % deleting all figure handlers
            delete(app.main_figure) 
            delete(app.zoom_figure);
            % deleting all axes handlers
            delete(app.ah1); delete(app.ah2); delete(app.ah3); 
            delete(app.ah4); delete(app.ah5); delete(app.ax); 
            % deleting app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RetinadetectionUIFigure = uifigure('Visible', 'off');
            app.RetinadetectionUIFigure.Name = 'Retina detection';
            app.RetinadetectionUIFigure.Icon = 'icon.jpg';
            app.RetinadetectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @RetinadetectionUIFigureCloseRequest, true);
            app.LoadrawimageButton = uibutton(app.RetinadetectionUIFigure, 'push');
            app.LoadrawimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawimageButtonPushed, true);
            app.LoadrawimageButton.Text = {'Load raw image'; ''};
            app.LoadpatternButton = uibutton(app.RetinadetectionUIFigure, 'push');
            app.LoadpatternButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpatternButtonPushed, true);
            app.LoadpatternButton.Text = 'Load pattern';
            app.CompareimagesButton = uibutton(app.RetinadetectionUIFigure, 'push');
            app.CompareimagesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareimagesButtonPushed, true);
            app.CompareimagesButton.Text = {'Compare images'; ''};
            app.SavedetectedpatternButton = uibutton(app.RetinadetectionUIFigure, 'push');
            app.SavedetectedpatternButton.ButtonPushedFcn = createCallbackFcn(app, @SavedetectedpatternButtonPushed, true);
            app.SavedetectedpatternButton.Text = {'Save detected pattern'; ''};
            app.Label_compare = uilabel(app.RetinadetectionUIFigure);
            app.Label_compare.Text = '';
            app.Label_save = uilabel(app.RetinadetectionUIFigure);
            app.Label_save.Text = '';
            app.ShowcomparisoninnewwindowButton = uibutton(app.RetinadetectionUIFigure, 'push');
            app.ShowcomparisoninnewwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowcomparisoninnewwindowButtonPushed, true);
            app.ShowcomparisoninnewwindowButton.Text = 'Show comparison in new window';
            app.Label_compare_new_figure = uilabel(app.RetinadetectionUIFigure);
            app.Label_compare_new_figure.Text = '';
            % Show the figure after all components are created
            app.RetinadetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retinaDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RetinadetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RetinadetectionUIFigure)
        end
    end
end