classdef retinaDetection2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadrawimageButton         matlab.ui.control.Button
        LoadpatternButton          matlab.ui.control.Button
        CompareimagesButton        matlab.ui.control.Button
        SavedetectedpatternButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        figure_handler % Description
        ah1
        ah2
        ah3
        pattern
        raw_image
        detection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.figure_handler = figure;
            app.figure_handler.Position = [550, 100, 600, 500];
            app.ah1 = axes('Parent',app.figure_handler,'Units','normalized','Position',[0.1 0.6 0.3 0.3]);
            app.ah2 = axes('Parent',app.figure_handler,'Units','normalized','Position',[0.1 0.2 0.3 0.3]);
            app.ah3 = axes('Parent',app.figure_handler,'Units','normalized','Position',[0.5 0.6 0.3 0.3]);

            text(app.ah1, 0.1, 0.5,'Load the raw image'); axis off
            set(app.ah1,'visible', 'off');
            set(app.ah3,'visible', 'off');
            
            text(app.ah2, 0.1, 0.5,'Load the pattern'); axis off
            set(app.ah2,'visible', 'off');


        end

        % Button pushed function: LoadrawimageButton
        function LoadrawimageButtonPushed(app, event)
            app.raw_image = raw_eye_image;
            
            imagesc(app.ah1, app.raw_image.resized_image); 
            set(app.ah1, 'Units', 'pixels', 'Position', [50, 300, 141.25, 146]);
            title(app.ah1, 'raw image')
            axis (app.ah1,'off')
            
            imagesc(app.ah3, app.raw_image.processed_image);
            colormap(app.ah3, 'gray');
            set(app.ah3, 'Units', 'pixels', 'Position', [250, 300, 141.25, 146]);
            title(app.ah3, 'detected pattern')
            axis (app.ah3,'off')
            
        end

        % Button pushed function: LoadpatternButton
        function LoadpatternButtonPushed(app, event)
            app.pattern = eye_pattern;
            app.pattern.get_final_eye_pattern;
            
            imagesc(app.ah2, app.pattern.processed_image);
            colormap(app.ah2, 'gray');
            set(app.ah2, 'Units', 'pixels', 'Position', [50, 50, 141.25, 146]);
            title(app.ah2, 'loaded pattern')
            axis (app.ah2,'off')
        end

        % Button pushed function: CompareimagesButton
        function CompareimagesButtonPushed(app, event)
            [best_pattern, detected, matchedP, matchedD, SSIM, score] = compare_two_images(app.pattern.processed_image, app.raw_image.processed_image);
            figure
            app.detection = showMatchedFeatures(best_pattern, detected, matchedP, matchedD,'montage');
%            t = ["Detected " num2str(score) " matched features"];
%            title(app.detection, t)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadrawimageButton = uibutton(app.UIFigure, 'push');
            app.LoadrawimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawimageButtonPushed, true);
            app.LoadrawimageButton.Text = {'Load raw image'; ''};
            app.LoadpatternButton = uibutton(app.UIFigure, 'push');
            app.LoadpatternButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpatternButtonPushed, true);
            app.LoadpatternButton.Text = 'Load pattern';
            app.CompareimagesButton = uibutton(app.UIFigure, 'push');
            app.CompareimagesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareimagesButtonPushed, true);
            app.CompareimagesButton.Text = {'Compare images'; ''};
            app.SavedetectedpatternButton = uibutton(app.UIFigure, 'push');
            app.SavedetectedpatternButton.Text = {'Save detected pattern'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retinaDetection2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end